classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        Filter                      matlab.ui.container.Tab
        Filter_path                 matlab.ui.control.TextArea
        Signal_path                 matlab.ui.control.TextArea
        Filter_bt                   matlab.ui.control.Button
        Choose_signal_bt            matlab.ui.control.Button
        Choose_filter_bt            matlab.ui.control.Button
        Raw_signal_ax               matlab.ui.control.UIAxes
        Spectrum                    matlab.ui.container.Tab
        Slider                      matlab.ui.control.Slider
        Label                       matlab.ui.control.Label
        ButtonGroup                 matlab.ui.container.ButtonGroup
        LinearButton                matlab.ui.control.RadioButton
        LogButton                   matlab.ui.control.RadioButton
        PSD_filtered_ax             matlab.ui.control.UIAxes
        PSD_raw_ax                  matlab.ui.control.UIAxes
        Corr                        matlab.ui.container.Tab
        Filtered_shiftedcorr_af_ax  matlab.ui.control.UIAxes
        Raw_shiftedcorr_af_ax       matlab.ui.control.UIAxes
        Filtered_corr_ax            matlab.ui.control.UIAxes
        Raw_corr_ax                 matlab.ui.control.UIAxes
        Filtered_corr_af_ax         matlab.ui.control.UIAxes
        Raw_corr_af_ax              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        signal_file
        filter_file
        raw_signal % Description
        filtered_signal
    end
    
    methods (Access = private)
        
        function results = func(app)

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Choose_filter_bt
        function Choose_filter_btButtonPushed(app, event)

            [app.filter_file,filter_path] = uigetfile('*.fcf')  %open a fcf file
            app.Filter_path.Value = fullfile(filter_path, app.filter_file); 
        end

        % Callback function: Choose_signal_bt, Raw_signal_ax
        function Choose_signal_btButtonPushed(app, event)
            [app.signal_file,signal_path] = uigetfile('*.txt')  %open a fcf file
            app.Signal_path.Value = fullfile(signal_path, app.signal_file);
            app.raw_signal = load(app.signal_file)
            plot(app.Raw_signal_ax,app.raw_signal)
            hold(app.Raw_signal_ax, 'on')

        end

        % Button pushed function: Filter_bt
        function Filter_btButtonPushed(app, event)
            filter_coeff=load(app.filter_file)
            app.filtered_signal=filter(filter_coeff,1,app.raw_signal)
            plot(app.Raw_signal_ax,app.filtered_signal)
        end

        % Button down function: Corr
        function CorrButtonDown(app, event)
            plot(app.Raw_corr_ax, app.raw_signal)
            plot(app.Filtered_corr_ax, app.filtered_signal)

            acf_raw=xcorr(app.raw_signal, 'coeff')
            plot(app.Raw_corr_af_ax, acf_raw)

            acf_filtered=xcorr(app.filtered_signal, 'coeff')
            plot(app.Filtered_corr_af_ax,acf_filtered)

        end

        % Button down function: Spectrum
        function SpectrumButtonDown(app, event)
            %пока не получилось
            p=app.Slider.Value
            psd_raw=pburg(app.raw_signal, p)
            plot(app.PSD_raw_ax,psd_raw)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Filter = uitab(app.TabGroup);
            app.Filter.Title = 'Фильтрация сигнала';
            app.Raw_signal_ax = uiaxes(app.Filter);
            title(app.Raw_signal_ax, 'Сигнал')
            xlabel(app.Raw_signal_ax, 'X')
            ylabel(app.Raw_signal_ax, 'Y')
            zlabel(app.Raw_signal_ax, 'Z')
            app.Raw_signal_ax.ButtonDownFcn = createCallbackFcn(app, @Choose_signal_btButtonPushed, true);
            app.Choose_filter_bt = uibutton(app.Filter, 'push');
            app.Choose_filter_bt.ButtonPushedFcn = createCallbackFcn(app, @Choose_filter_btButtonPushed, true);
            app.Choose_filter_bt.Text = 'Выбрать фильтр';
            app.Choose_signal_bt = uibutton(app.Filter, 'push');
            app.Choose_signal_bt.ButtonPushedFcn = createCallbackFcn(app, @Choose_signal_btButtonPushed, true);
            app.Choose_signal_bt.Text = 'Выбрать сигнал';
            app.Filter_bt = uibutton(app.Filter, 'push');
            app.Filter_bt.ButtonPushedFcn = createCallbackFcn(app, @Filter_btButtonPushed, true);
            app.Filter_bt.Text = 'Filter';
            app.Signal_path = uitextarea(app.Filter);
            app.Filter_path = uitextarea(app.Filter);
            app.Spectrum = uitab(app.TabGroup);
            app.Spectrum.Title = 'Спектральный анализ';
            app.Spectrum.ButtonDownFcn = createCallbackFcn(app, @SpectrumButtonDown, true);
            app.PSD_raw_ax = uiaxes(app.Spectrum);
            title(app.PSD_raw_ax, 'Title')
            xlabel(app.PSD_raw_ax, 'X')
            ylabel(app.PSD_raw_ax, 'Y')
            zlabel(app.PSD_raw_ax, 'Z')
            app.PSD_filtered_ax = uiaxes(app.Spectrum);
            title(app.PSD_filtered_ax, 'Title')
            xlabel(app.PSD_filtered_ax, 'X')
            ylabel(app.PSD_filtered_ax, 'Y')
            zlabel(app.PSD_filtered_ax, 'Z')
            app.ButtonGroup = uibuttongroup(app.Spectrum);
            app.LogButton = uiradiobutton(app.ButtonGroup);
            app.LogButton.Text = 'Log';
            app.LogButton.Value = true;
            app.LinearButton = uiradiobutton(app.ButtonGroup);
            app.LinearButton.Text = 'Linear';
            app.Label = uilabel(app.Spectrum);
            app.Label.Text = 'Выбор порядка';
            app.Slider = uislider(app.Spectrum);
            app.Slider.Limits = [8 40];
            app.Slider.MajorTicks = [8 15 25 40];
            app.Slider.MajorTickLabels = {'8', '15', '25', '40'};
            app.Slider.MinorTicks = [];
            app.Slider.Value = 8;
            app.Corr = uitab(app.TabGroup);
            app.Corr.Title = 'Корреляционный анализ';
            app.Corr.ButtonDownFcn = createCallbackFcn(app, @CorrButtonDown, true);
            app.Raw_corr_af_ax = uiaxes(app.Corr);
            title(app.Raw_corr_af_ax, {'АКФ до фильтрации'; 'несмещенная оценка'})
            xlabel(app.Raw_corr_af_ax, 'X')
            ylabel(app.Raw_corr_af_ax, 'Y')
            zlabel(app.Raw_corr_af_ax, 'Z')
            app.Filtered_corr_af_ax = uiaxes(app.Corr);
            title(app.Filtered_corr_af_ax, {'АКФ после фильтрации'; 'несмещенная оценка'})
            xlabel(app.Filtered_corr_af_ax, 'X')
            ylabel(app.Filtered_corr_af_ax, 'Y')
            zlabel(app.Filtered_corr_af_ax, 'Z')
            app.Raw_corr_ax = uiaxes(app.Corr);
            title(app.Raw_corr_ax, 'До фильтрации')
            xlabel(app.Raw_corr_ax, 'X')
            ylabel(app.Raw_corr_ax, 'Y')
            zlabel(app.Raw_corr_ax, 'Z')
            app.Filtered_corr_ax = uiaxes(app.Corr);
            title(app.Filtered_corr_ax, 'После фильтрации')
            xlabel(app.Filtered_corr_ax, 'X')
            ylabel(app.Filtered_corr_ax, 'Y')
            zlabel(app.Filtered_corr_ax, 'Z')
            app.Raw_shiftedcorr_af_ax = uiaxes(app.Corr);
            title(app.Raw_shiftedcorr_af_ax, {'АКФ до фильтрации'; 'смещенная оценка'})
            xlabel(app.Raw_shiftedcorr_af_ax, 'X')
            ylabel(app.Raw_shiftedcorr_af_ax, 'Y')
            zlabel(app.Raw_shiftedcorr_af_ax, 'Z')
            app.Filtered_shiftedcorr_af_ax = uiaxes(app.Corr);
            title(app.Filtered_shiftedcorr_af_ax, {'АКФ после фильтрации'; 'смещенная оценка'})
            xlabel(app.Filtered_shiftedcorr_af_ax, 'X')
            ylabel(app.Filtered_shiftedcorr_af_ax, 'Y')
            zlabel(app.Filtered_shiftedcorr_af_ax, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end